classdef DHparameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NOTEPrismaticjointalpha0Revolutejointd0Label  matlab.ui.control.Label
        ImpedencecontrolButton       matlab.ui.control.Button
        ComplianceControlButton      matlab.ui.control.Button
        MotionequationsButton        matlab.ui.control.Button
        currentpathisEditField       matlab.ui.control.EditField
        currentpathisEditFieldLabel  matlab.ui.control.Label
        ForwardDynamicsButton        matlab.ui.control.Button
        RowEditField                 matlab.ui.control.NumericEditField
        RowEditFieldLabel            matlab.ui.control.Label
        RemoveNewRowButton           matlab.ui.control.Button
        dEditField                   matlab.ui.control.NumericEditField
        dEditFieldLabel              matlab.ui.control.Label
        aEditField                   matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        ALPHAEditField               matlab.ui.control.NumericEditField
        ALPHAEditFieldLabel          matlab.ui.control.Label
        THETAEditField               matlab.ui.control.NumericEditField
        THETAEditFieldLabel          matlab.ui.control.Label
        AddNewRowButton              matlab.ui.control.Button
        Dh_table                     matlab.ui.control.Table
        ClearALLButton               matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        DHPARAMETERSLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        forward_dynamic; % Description
        motion_equation;
        pd_control;
        imp_control;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            currentFolder = pwd;
            app.currentpathisEditField.Value = currentFolder;
            %run '.\rvctools'\startup_rvc.m 
        end

        % Button pushed function: AddNewRowButton
        function AddNewRowButtonPushed(app, event)
            currentData = app.Dh_table.Data;
            alpha = app.ALPHAEditField.Value ;
            theta = app.THETAEditField.Value;
            a = app.aEditField.Value;
            d = app.dEditField.Value;
            nr = {theta alpha a d};
            
            app.Dh_table.Data = [currentData;nr];
            currentRow = app.RowEditField.Value;
            app.RowEditField.Value = currentRow + 1;
        end

        % Button pushed function: RemoveNewRowButton
        function RemoveNewRowButtonPushed(app, event)
            currentData = app.Dh_table.Data;

            % Check if there are rows in the table
            if ~isempty(currentData)
                % Remove the last row from the data
                updatedData = currentData(1:end-1, :);
                
                % Update the UITable data
                app.Dh_table.Data = updatedData;
                
                % Update the RowEditField value
                currentRow = app.RowEditField.Value;
                if currentRow > 1
                    app.RowEditField.Value = currentRow - 1;
                end
            end
        end

        % Button pushed function: ClearALLButton
        function ClearALLButtonPushed(app, event)
            app.Dh_table.Data = [];
        end

        % Button pushed function: ForwardDynamicsButton
        function ForwardDynamicsButtonPushed(app, event)
            app.forward_dynamic = Forward_dynamic(app.Dh_table.Data);
    
        end

        % Button pushed function: MotionequationsButton
        function MotionequationsButtonPushed(app, event)
            app.motion_equation = Motion_equation(app.Dh_table.Data);
        end

        % Button pushed function: ComplianceControlButton
        function ComplianceControlButtonPushed(app, event)
            app.pd_control = PD_control(app.Dh_table.Data);
        end

        % Button pushed function: ImpedencecontrolButton
        function ImpedencecontrolButtonPushed(app, event)
            app.imp_control = IMP_control(app.Dh_table.Data);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DHPARAMETERSLabel = uilabel(app.UIFigure);
            app.DHPARAMETERSLabel.Text = 'DH PARAMETERS';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '----------------------------------------------------------------------------------------------------------------------------------------------------------';
            app.ClearALLButton = uibutton(app.UIFigure, 'push');
            app.ClearALLButton.ButtonPushedFcn = createCallbackFcn(app, @ClearALLButtonPushed, true);
            app.ClearALLButton.Text = 'Clear ALL';
            app.Dh_table = uitable(app.UIFigure);
            app.Dh_table.ColumnName = {'THETA'; 'ALPHA'; 'a'; 'd'};
            app.Dh_table.RowName = {};
            app.AddNewRowButton = uibutton(app.UIFigure, 'push');
            app.AddNewRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewRowButtonPushed, true);
            app.AddNewRowButton.Text = 'Add New Row';
            app.THETAEditFieldLabel = uilabel(app.UIFigure);
            app.THETAEditFieldLabel.Text = 'THETA';
            app.THETAEditField = uieditfield(app.UIFigure, 'numeric');
            app.ALPHAEditFieldLabel = uilabel(app.UIFigure);
            app.ALPHAEditFieldLabel.Text = 'ALPHA';
            app.ALPHAEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.RemoveNewRowButton = uibutton(app.UIFigure, 'push');
            app.RemoveNewRowButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveNewRowButtonPushed, true);
            app.RemoveNewRowButton.Text = 'Remove New Row';
            app.RowEditFieldLabel = uilabel(app.UIFigure);
            app.RowEditFieldLabel.Text = 'Row';
            app.RowEditField = uieditfield(app.UIFigure, 'numeric');
            app.ForwardDynamicsButton = uibutton(app.UIFigure, 'push');
            app.ForwardDynamicsButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardDynamicsButtonPushed, true);
            app.ForwardDynamicsButton.Text = 'Forward Dynamics ';
            app.currentpathisEditFieldLabel = uilabel(app.UIFigure);
            app.currentpathisEditFieldLabel.Text = 'current path  is :';
            app.currentpathisEditField = uieditfield(app.UIFigure, 'text');
            app.MotionequationsButton = uibutton(app.UIFigure, 'push');
            app.MotionequationsButton.ButtonPushedFcn = createCallbackFcn(app, @MotionequationsButtonPushed, true);
            app.MotionequationsButton.Text = 'Motion equations';
            app.ComplianceControlButton = uibutton(app.UIFigure, 'push');
            app.ComplianceControlButton.ButtonPushedFcn = createCallbackFcn(app, @ComplianceControlButtonPushed, true);
            app.ComplianceControlButton.Text = 'Compliance Control';
            app.ImpedencecontrolButton = uibutton(app.UIFigure, 'push');
            app.ImpedencecontrolButton.ButtonPushedFcn = createCallbackFcn(app, @ImpedencecontrolButtonPushed, true);
            app.ImpedencecontrolButton.Text = 'Impedence control';
            app.NOTEPrismaticjointalpha0Revolutejointd0Label = uilabel(app.UIFigure);
            app.NOTEPrismaticjointalpha0Revolutejointd0Label.Text = {'NOTE: '; 'Prismatic joint: alpha 0 '; 'Revolute joint: d 0'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DHparameters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end