classdef Forward_dynamic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TypevaluesforForwardDynamicscalcluationEditField  matlab.ui.control.EditField
        TypevaluesforForwardDynamicscalcluationLabel  matlab.ui.control.Label
        GetDynamicsofSystemButton  matlab.ui.control.Button
        NoteSelectarowandpressbuttontodeleteLabel  matlab.ui.control.Label
        dynamic_table              matlab.ui.control.Table
        delete_row                 matlab.ui.control.Button
        add_row                    matlab.ui.control.Button
        ForwardDynamicsoftheManipulatorLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        dhs; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dh_parameters)
            %app.dhs = dh_parameters;
            run '.\rvctools'\startup_rvc.m 
        end

        % Button pushed function: add_row
        function add_rowButtonPushed(app, event)
            data = app.dynamic_table.Data;

            % Add a new row with default values (you can change this as needed)
            %newRow = {0, 0, 0, 0, 0, 0, 0}; % Assuming 3 columns with default values 0
            inputstr = app.TypevaluesforForwardDynamicscalcluationEditField.Value;
            vals = strsplit(inputstr, ' ');
            for i = 1:7
                newRow{1,i} = str2double(vals{1,i});
            end 
            % Append the new row to the data
            newData = [data; newRow];

            % Update the table with the new data
            app.dynamic_table.Data = newData;
        end

        % Button pushed function: delete_row
        function delete_rowButtonPushed(app, event)
            currentData = app.dynamic_table.Data;

            % Check if there are rows in the table
            if ~isempty(currentData)
                % Remove the last row from the data
                updatedData = currentData(1:end-1, :);
                
                % Update the UITable data
                app.dynamic_table.Data = updatedData;
            end
            
        end

        % Button pushed function: GetDynamicsofSystemButton
        function GetDynamicsofSystemButtonPushed(app, event)
            
            dh = cell2mat(app.dhs);
%             lengths = cell2mat(app.dynamic_table(:,1))'
%             masses = cell2mat(app.dynamic_table(:,2))'
%             ratios = cell2mat(app.dynamic_table(:,3))'
%             IL = cell2mat(app.dynamic_table(:,4))'
%             IM = cell2mat(app.dynamic_table(:,5))'
%             q0 = cell2mat(app.dynamic_table(:,6))'
%             qd0 = cell2mat(app.dynamic_table(:,7))'
            data = app.dynamic_table.Data;  % Get the cell array data
            lengths = [data{:, 1}];  % Extract lengths column
            masses = [data{:, 2}];   % Extract masses column
            ratios = [data{:, 3}];   % Extract ratios column
            IL = [data{:, 4}];       % Extract IL column
            IM = [data{:, 5}];       % Extract IM column
            q0 = [data{:, 6}];       % Extract q0 column
            qd0 = [data{:, 7}];      % Extract qd0 column

            dynamic_of_system(dh,lengths,masses,ratios,IL,IM,q0,qd0);
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ForwardDynamicsoftheManipulatorLabel = uilabel(app.UIFigure);
            app.ForwardDynamicsoftheManipulatorLabel.Text = 'Forward Dynamics of the Manipulator ';
            app.add_row = uibutton(app.UIFigure, 'push');
            app.add_row.ButtonPushedFcn = createCallbackFcn(app, @add_rowButtonPushed, true);
            app.add_row.Text = '+';
            app.delete_row = uibutton(app.UIFigure, 'push');
            app.delete_row.ButtonPushedFcn = createCallbackFcn(app, @delete_rowButtonPushed, true);
            app.delete_row.Text = '-';
            app.dynamic_table = uitable(app.UIFigure);
            app.dynamic_table.ColumnName = {'Length of Links(m)'; 'Mass of Links(Kg)'; 'Ratios'; 'Links Intertia(kgm^2)'; 'Motor Interia(kgm^2)'; 'q_0'; 'q_d0'};
            app.dynamic_table.RowName = {};
            app.dynamic_table.ColumnEditable = true;
            app.NoteSelectarowandpressbuttontodeleteLabel = uilabel(app.UIFigure);
            app.NoteSelectarowandpressbuttontodeleteLabel.Text = 'Note: Select a row and press " - " button to delete.';
            app.GetDynamicsofSystemButton = uibutton(app.UIFigure, 'push');
            app.GetDynamicsofSystemButton.ButtonPushedFcn = createCallbackFcn(app, @GetDynamicsofSystemButtonPushed, true);
            app.GetDynamicsofSystemButton.Text = 'Get Dynamics of System ';
            app.TypevaluesforForwardDynamicscalcluationLabel = uilabel(app.UIFigure);
            app.TypevaluesforForwardDynamicscalcluationLabel.Text = {'Type values for'; ' Forward Dynamics '; 'calcluation'};
            app.TypevaluesforForwardDynamicscalcluationEditField = uieditfield(app.UIFigure, 'text');
            app.TypevaluesforForwardDynamicscalcluationEditField.Value = '0.5 50 110 10 0.02 30 0 ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Forward_dynamic(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end