classdef IMP_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PutthevaluestoimpcontrolEditField  matlab.ui.control.EditField
        PutthevaluestoextarctImpedencecontrolLabel  matlab.ui.control.Label
        GenerateImpedenceControlButton  matlab.ui.control.Button
        remove                          matlab.ui.control.Button
        Add                             matlab.ui.control.Button
        IMP_Control_table               matlab.ui.control.Table
        IMPEDENCECONTROLLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        dhs; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dh_parameters)
             app.dhs = dh_parameters;
        end

        % Button pushed function: Add
        function AddButtonPushed(app, event)
            data = app.IMP_Control_table.Data;
            inputstr = app.PutthevaluestoimpcontrolEditField.Value;
            vals = strsplit(inputstr, ' ');
            for i = 1:27
                newRow{1,i} = str2double(vals{1,i});
            end 
            % Append the new row to the data
            newData = [data; newRow];

            % Update the table with the new data
            app.IMP_Control_table.Data = newData;
        end

        % Button pushed function: remove
        function removeButtonPushed(app, event)
            currentData = app.IMP_Control_table.Data;

            % Check if there are rows in the table
            if ~isempty(currentData)
                % Remove the last row from the data
                updatedData = currentData(1:end-1, :);
                
                % Update the UITable data
                app.IMP_Control_table.Data = updatedData;
            end
        end

        % Button pushed function: GenerateImpedenceControlButton
        function GenerateImpedenceControlButtonPushed(app, event)
            impedance_data = cell2mat(app.IMP_Control_table.Data)
            a1 = app.dhs(1,3);
            a2 = app.dhs(2,3);
            disp(a1);
            xd1 = impedance_data(1);
            xd2 = impedance_data(2);
            K_D = impedance_data(3);
            K_P = impedance_data(4);
            friction = impedance_data(5);
            IL1 = impedance_data(6);
            IL2 = impedance_data(7);
            mL1 = impedance_data(8);
            mL2 = impedance_data(9);
            mm2 = impedance_data(10);
            L1 = impedance_data(11);
            L2 = impedance_data(12);
            kr1 = impedance_data(13);
            kr2 = impedance_data(14);
            Im1 = impedance_data(15);
            Im2 = impedance_data(16);
            q1 = impedance_data(17);
            q2 = impedance_data(18);
            xdd = [impedance_data(19) impedance_data(20)];
            xddd = [impedance_data(21) impedance_data(22)];
            xr = [impedance_data(23) impedance_data(24)];
            stiffness = impedance_data(25);
            mdx = impedance_data(26);
            mdy = impedance_data(27);
            impedance_entrance(xd1, xd2, K_D, K_P, a1, a2, friction, IL1, IL2, mL1, mL2, mm2, L1, L2, kr1, kr2, Im1, Im2, q1, q2,xdd, xddd, xr, stiffness, mdx, mdy)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IMPEDENCECONTROLLabel = uilabel(app.UIFigure);
            app.IMPEDENCECONTROLLabel.Text = 'IMPEDENCE CONTROL';
            app.IMP_Control_table = uitable(app.UIFigure);
            app.IMP_Control_table.ColumnName = {'xd1'; 'xd2'; 'KD'; 'KP'; 'friction'; 'IL1'; 'IL2'; 'mL1'; 'mL2'; 'mm2'; 'L1'; 'L2'; 'kr1'; 'kr2'; 'Im1'; 'Im2'; 'q1'; 'q2'; 'xdd1'; 'xdd2'; 'xddd1'; 'xddd2'; 'xr1'; 'xr2'; 'stiffness'; 'mdx'; 'mdy'};
            app.IMP_Control_table.RowName = {};
            app.Add = uibutton(app.UIFigure, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.Add.Text = '+';
            app.remove = uibutton(app.UIFigure, 'push');
            app.remove.ButtonPushedFcn = createCallbackFcn(app, @removeButtonPushed, true);
            app.remove.Text = '-';
            app.GenerateImpedenceControlButton = uibutton(app.UIFigure, 'push');
            app.GenerateImpedenceControlButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateImpedenceControlButtonPushed, true);
            app.GenerateImpedenceControlButton.Text = {'Generate'; 'Impedence Control'};
            app.PutthevaluestoextarctImpedencecontrolLabel = uilabel(app.UIFigure);
            app.PutthevaluestoextarctImpedencecontrolLabel.Text = {'Put the values to'; ' extarct Impedence control'};
            app.PutthevaluestoimpcontrolEditField = uieditfield(app.UIFigure, 'text');
            app.PutthevaluestoimpcontrolEditField.Value = '1 0 10 10 1 1 1 1 1 1 1 1 1 1 1 1 0.5 0.5 1 0 1 0 1 0 100 1 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IMP_control(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end