classdef Motion_equation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Equations                     matlab.ui.control.Label
        GenerateMotionequationButton  matlab.ui.control.Button
        NoteToextarctmotionequationpleasesplityourdatawithspacebarLabel  matlab.ui.control.Label
        PutthevaluestoextarctmotionequationEditField  matlab.ui.control.EditField
        PutthevaluestoextarctmotionequationLabel  matlab.ui.control.Label
        remove                        matlab.ui.control.Button
        Add                           matlab.ui.control.Button
        motion_equation_table         matlab.ui.control.Table
        MotionEquationLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        dhs; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dh_parameters)
            app.dhs = dh_parameters;
        end

        % Button pushed function: Add
        function AddButtonPushed(app, event)
            data = app.motion_equation_table.Data;

            inputstr = app.PutthevaluestoextarctmotionequationEditField.Value;
            %vals = strsplit(inputstr,' ');
            vals = str2double(strsplit(inputstr, ' '));
%             for i = 1:7
%                 newRow{1,i} = str2double(vals{1,i});
%             end 
            newRow = vals(:)';  
            % Append the new row to the data
            newData = [data; newRow];

            % Update the table with the new data
            app.motion_equation_table.Data = newData;
        end

        % Button pushed function: remove
        function removeButtonPushed(app, event)
            currentData = app.motion_equation_table.Data;

            % Check if there are rows in the table
            if ~isempty(currentData)
                % Remove the last row from the data
                updatedData = currentData(1:end-1, :);
                
                % Update the UITable data
                app.motion_equation_table.Data = updatedData;
            end
        end

        % Button pushed function: GenerateMotionequationButton
        function GenerateMotionequationButtonPushed(app, event)
            
            re = motion_equation(cell2mat(app.dhs),app.motion_equation_table.Data)
            strtemp = ""
            [~,num_tau] = size(re)
            for i=1:num_tau
                strtemp = strtemp+newline+sprintf("tau%d=%s",i,re(i));
          
            end
            app.Equations.Text = join(strtemp,'=======');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MotionEquationLabel = uilabel(app.UIFigure);
            app.MotionEquationLabel.Text = 'Motion Equation ';
            app.motion_equation_table = uitable(app.UIFigure);
            app.motion_equation_table.ColumnName = {'Kr'; 'ml'; 'mm'; 'iL'; 'im'; 'tau'; 'type'};
            app.motion_equation_table.RowName = {};
            app.Add = uibutton(app.UIFigure, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.Add.Text = '+';
            app.remove = uibutton(app.UIFigure, 'push');
            app.remove.ButtonPushedFcn = createCallbackFcn(app, @removeButtonPushed, true);
            app.remove.Text = '-';
            app.PutthevaluestoextarctmotionequationLabel = uilabel(app.UIFigure);
            app.PutthevaluestoextarctmotionequationLabel.Text = {'Put the values to'; ' extarct motion equation'};
            app.PutthevaluestoextarctmotionequationEditField = uieditfield(app.UIFigure, 'text');
            app.PutthevaluestoextarctmotionequationEditField.Value = '0.5 50 110 10 0.02 -50 2';
            app.NoteToextarctmotionequationpleasesplityourdatawithspacebarLabel = uilabel(app.UIFigure);
            app.NoteToextarctmotionequationpleasesplityourdatawithspacebarLabel.Text = {'Note: To extarct motion equation please split your data with space bar'; '           Type = 1 (Prisimatic) and =2 (revolute) '; ''};
            app.GenerateMotionequationButton = uibutton(app.UIFigure, 'push');
            app.GenerateMotionequationButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMotionequationButtonPushed, true);
            app.GenerateMotionequationButton.Text = {'Generate'; ' Motion equation'};
            app.Equations = uilabel(app.UIFigure);
            app.Equations.Text = 'Equations are:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Motion_equation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end