classdef PD_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LKrmlmmiLimKDKpfxd1xd2xd3Label  matlab.ui.control.Label
        PutthevaluestopdcontrolEditField  matlab.ui.control.EditField
        PutthevaluestoextarctPDControlLabel  matlab.ui.control.Label
        GeneratePDControlButton         matlab.ui.control.Button
        remove                          matlab.ui.control.Button
        Add                             matlab.ui.control.Button
        PD_Control_table                matlab.ui.control.Table
        PDCONTROLLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        dhs; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dh_parameters)
            app.dhs = dh_parameters;
        end

        % Button pushed function: GeneratePDControlButton
        function GeneratePDControlButtonPushed(app, event)
            dh = cell2mat(app.dhs);
            data = app.PD_Control_table.Data;
            %dh = [0 0 1 0;0 0 1 0;0 0 1 0];
            %disp(data);
            %disp(dh);
            % Get the cell array data
            lengths = [data{:, 1}];
            %disp(lengths(1));% Extract lengths column
            kr = [data{:, 2}];   % Extract masses column
            ML = [data{:, 3}];   % Extract ratios column
            MM= [data{:, 4}];       % Extract IL column
            IL = [data{:, 5}];       % Extract IM column
            IM = [data{:, 6}];       % Extract q0 column
            K_D = [data{:, 7}];      % Extract qd0 column
            K_P = [data{:, 8}];
            friction = [data{:,9}];
            xd = [data{:,10} data{:,11} data{:,12}];
            
            pd_control(dh,lengths,kr,ML,MM,IL,IM,K_D,K_P,friction,xd);
        end

        % Button pushed function: Add
        function AddButtonPushed(app, event)
            data = app.PD_Control_table.Data;
            inputstr = app.PutthevaluestopdcontrolEditField.Value;
            vals = strsplit(inputstr, ' ');
            for i = 1:12
                newRow{1,i} = str2double(vals{1,i});
            end 
            % Append the new row to the data
            newData = [data; newRow];

            % Update the table with the new data
            app.PD_Control_table.Data = newData;
        end

        % Button pushed function: remove
        function removeButtonPushed(app, event)
            currentData = app.PD_Control_table.Data;

            % Check if there are rows in the table
            if ~isempty(currentData)
                % Remove the last row from the data
                updatedData = currentData(1:end-1, :);
                
                % Update the UITable data
                app.PD_Control_table.Data = updatedData;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PDCONTROLLabel = uilabel(app.UIFigure);
            app.PDCONTROLLabel.Text = 'PD CONTROL';
            app.PD_Control_table = uitable(app.UIFigure);
            app.PD_Control_table.ColumnName = {'L'; 'Kr'; 'ml'; 'mm'; 'iL'; 'im'; 'K_D'; 'K_P'; 'friction'; 'xd1'; 'xd2'; 'xd3'};
            app.PD_Control_table.RowName = {};
            app.Add = uibutton(app.UIFigure, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.Add.Text = '+';
            app.remove = uibutton(app.UIFigure, 'push');
            app.remove.ButtonPushedFcn = createCallbackFcn(app, @removeButtonPushed, true);
            app.remove.Text = '-';
            app.GeneratePDControlButton = uibutton(app.UIFigure, 'push');
            app.GeneratePDControlButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePDControlButtonPushed, true);
            app.GeneratePDControlButton.Text = {'Generate'; 'PD Control'};
            app.PutthevaluestoextarctPDControlLabel = uilabel(app.UIFigure);
            app.PutthevaluestoextarctPDControlLabel.Text = {'Put the values to'; ' extarct PD Control'};
            app.PutthevaluestopdcontrolEditField = uieditfield(app.UIFigure, 'text');
            app.PutthevaluestopdcontrolEditField.Value = '0.5 110 50 50 11 0.03 200 450 1 1 1 0';
            app.LKrmlmmiLimKDKpfxd1xd2xd3Label = uilabel(app.UIFigure);
            app.LKrmlmmiLimKDKpfxd1xd2xd3Label.Text = 'L  Kr  ml mm iL im KD Kp f xd1 xd2 xd3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PD_control(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end